classdef DashBoard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        TabGroup          matlab.ui.container.TabGroup
        PlantTb           matlab.ui.container.Tab
        Pl_NameTx         matlab.ui.control.EditField
        Pl_OwnerTx        matlab.ui.control.EditField
        Pl_IDTx           matlab.ui.control.NumericEditField
        Pl_LocationPanel  matlab.ui.container.Panel
        Pl_LatitudeCb     matlab.ui.control.DropDown
        Pl_LongitudeCb    matlab.ui.control.DropDown
        Pl_LatitudeLb     matlab.ui.control.Label
        Pl_LongitudeLb    matlab.ui.control.Label
        Pl_LocationLb     matlab.ui.control.Label
        Pl_LatitudeTx     matlab.ui.control.NumericEditField
        Pl_LongitudeTx    matlab.ui.control.NumericEditField
        Pl_ImageAx        matlab.ui.control.UIAxes
        Pl_NavPanel       matlab.ui.container.Panel
        Pl_ApplyBt        matlab.ui.control.Button
        Pl_NextBt         matlab.ui.control.Button
        Pl_PrevBt         matlab.ui.control.Button
        Pl_AddNewPanel    matlab.ui.container.Panel
        Pl_AddBt          matlab.ui.control.Button
        Pl_NotesTx        matlab.ui.control.TextArea
        Pl_IDLb           matlab.ui.control.Label
        Pl_NameLb         matlab.ui.control.Label
        Pl_OwnerLb        matlab.ui.control.Label
        Pl_NotesLb        matlab.ui.control.Label
        InterventionTb    matlab.ui.container.Tab
        ln_NavPanel       matlab.ui.container.Panel
        In_ApplyBt        matlab.ui.control.Button
        In_NextBt         matlab.ui.control.Button
        In_PrevBt         matlab.ui.control.Button
        In_AddNewPanel    matlab.ui.container.Panel
        In_AddBt          matlab.ui.control.Button
        In_IDTx           matlab.ui.control.NumericEditField
        In_PlantCb        matlab.ui.control.DropDown
        In_NotesTx        matlab.ui.control.TextArea
        In_PlantLb        matlab.ui.control.Label
        In_IDLb           matlab.ui.control.Label
        In_NotesLb        matlab.ui.control.Label
        In_DateLb         matlab.ui.control.Label
        CpnditionsPanel   matlab.ui.container.Panel
        In_ConditionsLb   matlab.ui.control.Label
        In_TempTx         matlab.ui.control.NumericEditField
        In_TempLb         matlab.ui.control.Label
        In_DegLb          matlab.ui.control.Label
        In_IrradTx        matlab.ui.control.NumericEditField
        In_IrradLb        matlab.ui.control.Label
        In_Wm2Panel       matlab.ui.container.Panel
        In_WLb            matlab.ui.control.Label
        In_mLb            matlab.ui.control.Label
        In_slashLb        matlab.ui.control.Label
        In_squaredLb      matlab.ui.control.Label
        In_WeatherCb      matlab.ui.control.DropDown
        In_WeatherLb      matlab.ui.control.Label
        In_PathTx         matlab.ui.control.EditField
        In_PathLb         matlab.ui.control.Label
        In_BrowseBt       matlab.ui.control.Button
        PanelTb           matlab.ui.container.Tab
        Pn_NavPanel       matlab.ui.container.Panel
        Pn_ApplyBt        matlab.ui.control.Button
        Pn_NextBt         matlab.ui.control.Button
        Pn_PrevBt         matlab.ui.control.Button
        Pn_AddNewPanel    matlab.ui.container.Panel
        Pn_AddBt          matlab.ui.control.Button
        Pn_IDTx           matlab.ui.control.NumericEditField
        Pn_PlantCb        matlab.ui.control.DropDown
        Pn_NotesTx        matlab.ui.control.TextArea
        Pn_PlantLb        matlab.ui.control.Label
        Pn_IDLb           matlab.ui.control.Label
        Pn_NotesLb        matlab.ui.control.Label
        PositionPanel     matlab.ui.container.Panel
        Pn_SectionTx      matlab.ui.control.NumericEditField
        Pn_SectionLb      matlab.ui.control.Label
        Pn_RowTx          matlab.ui.control.NumericEditField
        Pn_RowLb          matlab.ui.control.Label
        Pn_ColTx          matlab.ui.control.NumericEditField
        Pn_ColLb          matlab.ui.control.Label
        Pn_PathTx         matlab.ui.control.EditField
        Pn_PathLb         matlab.ui.control.Label
        Pn_BrowseBt       matlab.ui.control.Button
        Pn_IntervCb       matlab.ui.control.DropDown
        Pn_IntervLb       matlab.ui.control.Label
        Pn_ImageAx        matlab.ui.control.UIAxes
        Pn_PosLb          matlab.ui.control.Label
        Pn_FaultPanel     matlab.ui.container.Panel
        DropDown          matlab.ui.control.DropDown
        Pn_Fault1Lb       matlab.ui.control.Label
        Pn_Fault2Lb       matlab.ui.control.Label
        Pn_Fault1Cb       matlab.ui.control.DropDown
        Pn_Fault2Cb       matlab.ui.control.Label
        Pn_Fault1Bt       matlab.ui.control.Button
        Pn_Fault2Bt       matlab.ui.control.Button
        Pn_YearsLb        matlab.ui.control.Label
        Pn_AgeTx          matlab.ui.control.NumericEditField
        Pn_AgeLb          matlab.ui.control.Label
        Pn_TypeLb         matlab.ui.control.Label
        Pn_TypeCb         matlab.ui.control.DropDown
        Tab               matlab.ui.container.Tab
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Delete Axis
            % from Plant figure
            app.Pl_ImageAx.XAxis.TickLabels = {};
            app.Pl_ImageAx.YAxis.TickLabels = {};
            % from Panel figure
            app.Pn_ImageAx.XAxis.TickLabels = {};
            app.Pn_ImageAx.YAxis.TickLabels = {};
            
            % Configure date picker
            plantPos = get(app.In_PlantCb, 'Position');
            labelPos = get(app.In_DateLb, 'Position');
            In_dateDp = uidatepicker(app.InterventionTb, 'DisplayFormat', 'dd-MMM-yyyy',...
                'Position', [plantPos(1) labelPos(2) plantPos(3) 22],...
                'Value', datetime('now'));
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PlantTb = uitab(app.TabGroup);
            app.PlantTb.Title = 'Plant';
            app.Pl_NameTx = uieditfield(app.PlantTb, 'text');
            app.Pl_OwnerTx = uieditfield(app.PlantTb, 'text');
            app.Pl_IDTx = uieditfield(app.PlantTb, 'numeric');
            app.Pl_IDTx.ValueDisplayFormat = '%.0f';
            app.Pl_IDTx.Editable = 'off';
            app.Pl_IDTx.Enable = 'off';
            app.Pl_LocationPanel = uipanel(app.PlantTb);
            app.Pl_LatitudeCb = uidropdown(app.Pl_LocationPanel);
            app.Pl_LatitudeCb.Items = {'N', 'S'};
            app.Pl_LatitudeCb.Value = 'N';
            app.Pl_LongitudeCb = uidropdown(app.Pl_LocationPanel);
            app.Pl_LongitudeCb.Items = {'E', 'W'};
            app.Pl_LongitudeCb.Value = 'E';
            app.Pl_LatitudeLb = uilabel(app.Pl_LocationPanel);
            app.Pl_LatitudeLb.Text = 'Latitude';
            app.Pl_LongitudeLb = uilabel(app.Pl_LocationPanel);
            app.Pl_LongitudeLb.Text = 'Longitude';
            app.Pl_LocationLb = uilabel(app.Pl_LocationPanel);
            app.Pl_LocationLb.Text = 'Location';
            app.Pl_LatitudeTx = uieditfield(app.Pl_LocationPanel, 'numeric');
            app.Pl_LatitudeTx.Limits = [0 90];
            app.Pl_LatitudeTx.ValueDisplayFormat = '%.3f';
            app.Pl_LongitudeTx = uieditfield(app.Pl_LocationPanel, 'numeric');
            app.Pl_LongitudeTx.Limits = [0 180];
            app.Pl_LongitudeTx.ValueDisplayFormat = '%.3f';
            app.Pl_ImageAx = uiaxes(app.PlantTb);
            app.Pl_ImageAx.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Pl_NavPanel = uipanel(app.PlantTb);
            app.Pl_ApplyBt = uibutton(app.Pl_NavPanel, 'push');
            app.Pl_ApplyBt.Text = 'Apply';
            app.Pl_NextBt = uibutton(app.Pl_NavPanel, 'push');
            app.Pl_NextBt.Icon = 'Next.png';
            app.Pl_NextBt.Text = '';
            app.Pl_PrevBt = uibutton(app.Pl_NavPanel, 'push');
            app.Pl_PrevBt.Icon = 'Prev.png';
            app.Pl_PrevBt.Text = '';
            app.Pl_AddNewPanel = uipanel(app.PlantTb);
            app.Pl_AddBt = uibutton(app.Pl_AddNewPanel, 'push');
            app.Pl_AddBt.Text = 'Add New';
            app.Pl_NotesTx = uitextarea(app.PlantTb);
            app.Pl_IDLb = uilabel(app.PlantTb);
            app.Pl_IDLb.Text = 'ID';
            app.Pl_NameLb = uilabel(app.PlantTb);
            app.Pl_NameLb.Text = 'Name';
            app.Pl_OwnerLb = uilabel(app.PlantTb);
            app.Pl_OwnerLb.Text = 'Owner';
            app.Pl_NotesLb = uilabel(app.PlantTb);
            app.Pl_NotesLb.Text = 'Notes';
            app.InterventionTb = uitab(app.TabGroup);
            app.InterventionTb.Title = 'Intervention';
            app.ln_NavPanel = uipanel(app.InterventionTb);
            app.In_ApplyBt = uibutton(app.ln_NavPanel, 'push');
            app.In_ApplyBt.Text = 'Apply';
            app.In_NextBt = uibutton(app.ln_NavPanel, 'push');
            app.In_NextBt.Icon = 'Next.png';
            app.In_NextBt.Text = '';
            app.In_PrevBt = uibutton(app.ln_NavPanel, 'push');
            app.In_PrevBt.Icon = 'Prev.png';
            app.In_PrevBt.Text = '';
            app.In_AddNewPanel = uipanel(app.InterventionTb);
            app.In_AddBt = uibutton(app.In_AddNewPanel, 'push');
            app.In_AddBt.Text = 'Add New';
            app.In_IDTx = uieditfield(app.InterventionTb, 'numeric');
            app.In_IDTx.ValueDisplayFormat = '%.0f';
            app.In_IDTx.Editable = 'off';
            app.In_IDTx.Enable = 'off';
            app.In_PlantCb = uidropdown(app.InterventionTb);
            app.In_NotesTx = uitextarea(app.InterventionTb);
            app.In_PlantLb = uilabel(app.InterventionTb);
            app.In_PlantLb.Text = 'Plant';
            app.In_IDLb = uilabel(app.InterventionTb);
            app.In_IDLb.Text = 'ID';
            app.In_NotesLb = uilabel(app.InterventionTb);
            app.In_NotesLb.Text = 'Notes';
            app.In_DateLb = uilabel(app.InterventionTb);
            app.In_DateLb.Text = 'Date';
            app.CpnditionsPanel = uipanel(app.InterventionTb);
            app.In_ConditionsLb = uilabel(app.CpnditionsPanel);
            app.In_ConditionsLb.Text = 'Conditions';
            app.In_TempTx = uieditfield(app.CpnditionsPanel, 'numeric');
            app.In_TempLb = uilabel(app.CpnditionsPanel);
            app.In_TempLb.Text = 'Temperature';
            app.In_DegLb = uilabel(app.CpnditionsPanel);
            app.In_DegLb.Text = '°C';
            app.In_IrradTx = uieditfield(app.CpnditionsPanel, 'numeric');
            app.In_IrradLb = uilabel(app.CpnditionsPanel);
            app.In_IrradLb.Text = 'Irradiance';
            app.In_Wm2Panel = uipanel(app.CpnditionsPanel);
            app.In_WLb = uilabel(app.In_Wm2Panel);
            app.In_WLb.Text = 'W';
            app.In_mLb = uilabel(app.In_Wm2Panel);
            app.In_mLb.Text = 'm';
            app.In_slashLb = uilabel(app.In_Wm2Panel);
            app.In_slashLb.Text = '/';
            app.In_squaredLb = uilabel(app.In_Wm2Panel);
            app.In_squaredLb.Text = '2';
            app.In_WeatherCb = uidropdown(app.CpnditionsPanel);
            app.In_WeatherCb.Items = {'Sunny', 'Overcast', 'Cloudy', 'Raining'};
            app.In_WeatherCb.Editable = 'on';
            app.In_WeatherCb.Value = 'Sunny';
            app.In_WeatherLb = uilabel(app.CpnditionsPanel);
            app.In_WeatherLb.Text = 'Weather';
            app.In_PathTx = uieditfield(app.InterventionTb, 'text');
            app.In_PathLb = uilabel(app.InterventionTb);
            app.In_PathLb.Text = 'Images Path';
            app.In_BrowseBt = uibutton(app.InterventionTb, 'push');
            app.In_BrowseBt.Icon = 'Folder.png';
            app.In_BrowseBt.Text = '';
            app.PanelTb = uitab(app.TabGroup);
            app.PanelTb.Title = 'Panel';
            app.Pn_NavPanel = uipanel(app.PanelTb);
            app.Pn_ApplyBt = uibutton(app.Pn_NavPanel, 'push');
            app.Pn_ApplyBt.Text = 'Apply';
            app.Pn_NextBt = uibutton(app.Pn_NavPanel, 'push');
            app.Pn_NextBt.Icon = 'Next.png';
            app.Pn_NextBt.Text = '';
            app.Pn_PrevBt = uibutton(app.Pn_NavPanel, 'push');
            app.Pn_PrevBt.Icon = 'Prev.png';
            app.Pn_PrevBt.Text = '';
            app.Pn_AddNewPanel = uipanel(app.PanelTb);
            app.Pn_AddBt = uibutton(app.Pn_AddNewPanel, 'push');
            app.Pn_AddBt.Text = 'Add New';
            app.Pn_IDTx = uieditfield(app.PanelTb, 'numeric');
            app.Pn_IDTx.ValueDisplayFormat = '%.0f';
            app.Pn_IDTx.Editable = 'off';
            app.Pn_IDTx.Enable = 'off';
            app.Pn_PlantCb = uidropdown(app.PanelTb);
            app.Pn_NotesTx = uitextarea(app.PanelTb);
            app.Pn_PlantLb = uilabel(app.PanelTb);
            app.Pn_PlantLb.Text = 'Plant';
            app.Pn_IDLb = uilabel(app.PanelTb);
            app.Pn_IDLb.Text = 'ID';
            app.Pn_NotesLb = uilabel(app.PanelTb);
            app.Pn_NotesLb.Text = 'Notes';
            app.PositionPanel = uipanel(app.PanelTb);
            app.Pn_SectionTx = uieditfield(app.PositionPanel, 'numeric');
            app.Pn_SectionLb = uilabel(app.PositionPanel);
            app.Pn_SectionLb.Text = 'Sec';
            app.Pn_RowTx = uieditfield(app.PositionPanel, 'numeric');
            app.Pn_RowLb = uilabel(app.PositionPanel);
            app.Pn_RowLb.Text = 'Row';
            app.Pn_ColTx = uieditfield(app.PositionPanel, 'numeric');
            app.Pn_ColLb = uilabel(app.PositionPanel);
            app.Pn_ColLb.Text = 'Col';
            app.Pn_PathTx = uieditfield(app.PanelTb, 'text');
            app.Pn_PathLb = uilabel(app.PanelTb);
            app.Pn_PathLb.Text = 'Images Path';
            app.Pn_BrowseBt = uibutton(app.PanelTb, 'push');
            app.Pn_BrowseBt.Icon = 'Folder.png';
            app.Pn_BrowseBt.Text = '';
            app.Pn_IntervCb = uidropdown(app.PanelTb);
            app.Pn_IntervLb = uilabel(app.PanelTb);
            app.Pn_IntervLb.Text = 'Intervention';
            app.Pn_ImageAx = uiaxes(app.PanelTb);
            app.Pn_PosLb = uilabel(app.PanelTb);
            app.Pn_PosLb.Text = 'Position';
            app.Pn_FaultPanel = uipanel(app.PanelTb);
            app.DropDown = uidropdown(app.Pn_FaultPanel);
            app.DropDown.Items = {'None', 'Fault'};
            app.DropDown.Value = 'None';
            app.Pn_Fault1Lb = uilabel(app.Pn_FaultPanel);
            app.Pn_Fault1Lb.Text = 'Fault 1';
            app.Pn_Fault2Lb = uilabel(app.Pn_FaultPanel);
            app.Pn_Fault2Lb.Text = 'Fault 2';
            app.Pn_Fault1Cb = uidropdown(app.Pn_FaultPanel);
            app.Pn_Fault1Cb.Items = {'None', 'Fault'};
            app.Pn_Fault1Cb.Value = 'None';
            app.Pn_Fault2Cb = uilabel(app.Pn_FaultPanel);
            app.Pn_Fault2Cb.Text = 'Faults';
            app.Pn_Fault1Bt = uibutton(app.Pn_FaultPanel, 'push');
            app.Pn_Fault1Bt.Text = '?';
            app.Pn_Fault2Bt = uibutton(app.Pn_FaultPanel, 'push');
            app.Pn_Fault2Bt.Text = '?';
            app.Pn_YearsLb = uilabel(app.PanelTb);
            app.Pn_YearsLb.Text = 'y';
            app.Pn_AgeTx = uieditfield(app.PanelTb, 'numeric');
            app.Pn_AgeTx.Value = 1;
            app.Pn_AgeLb = uilabel(app.PanelTb);
            app.Pn_AgeLb.Text = 'Age';
            app.Pn_TypeLb = uilabel(app.PanelTb);
            app.Pn_TypeLb.Text = 'Type';
            app.Pn_TypeCb = uidropdown(app.PanelTb);
            app.Pn_TypeCb.Items = {'Undefined'};
            app.Pn_TypeCb.Value = 'Undefined';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DashBoard

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end